classdef Zsensing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Impedance_Sensing  matlab.ui.Figure
        ModiolarLamp1      matlab.ui.control.Lamp
        MiddleLamp1        matlab.ui.control.Lamp
        LateralLamp1       matlab.ui.control.Lamp
        SerialPanel        matlab.ui.container.Panel
        DisconnectButton   matlab.ui.control.Button
        ConnectButton      matlab.ui.control.Button
        RefreshButton      matlab.ui.control.Button
        PortLabel          matlab.ui.control.Label
        PortDropDown       matlab.ui.control.DropDown
        StartButton        matlab.ui.control.Button
        EA_Image           matlab.ui.control.Image
        ModiolarLamp2      matlab.ui.control.Lamp
        MiddleLamp2        matlab.ui.control.Lamp
        LateralLamp2       matlab.ui.control.Lamp
        ModiolarLamp3      matlab.ui.control.Lamp
        MiddleLamp3        matlab.ui.control.Lamp
        LateralLamp3       matlab.ui.control.Lamp
        ModiolarLamp4      matlab.ui.control.Lamp
        MiddleLamp4        matlab.ui.control.Lamp
        LateralLamp4       matlab.ui.control.Lamp
        Z1                 matlab.ui.control.NumericEditField
        Z2                 matlab.ui.control.NumericEditField
        Z3                 matlab.ui.control.NumericEditField
        Z4                 matlab.ui.control.NumericEditField
        ModiolarLabel      matlab.ui.control.Label
        MiddleLabel        matlab.ui.control.Label
        LateralLabel       matlab.ui.control.Label
        Axes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Serial object
        nets % trained networks
        update_timer
        count 
        max_samples 
        Zdata
        Zdata_min = [0,0,0,0] % minimum values up to current time
        predictions
        ui_refresh_rate = 6; % [samples]
        update_timer_period = 1/100; % [s]
        hZ_line 
        hZ_marker 
        colors = [0 0 1; 1 0 0; 0 1 0; 0 0 .172]; % plot colors

    end
    
    methods (Access = private)
        
        function UpdateCallback(app, m_timer, ~)
            % TODO
            if ((app.count>app.max_samples))
                app.count = 0;
            end

            % check for data
            if app.s.BytesAvailable
                
                % read data
                data_string = fgetl(app.s);
                data_num = cell2mat(textscan(data_string, '%f', 4, 'Delimiter',','));
                
                % if correct length -> store and compute prediction
                if length(data_num)==4
                    app.count = app.count+1;
                    app.Zdata(app.count,:) = data_num;
                    app.Zdata_min = min([app.Zdata_min; app.Zdata(app.count,:)]);
                    for ii=1:4            
                        [app.nets{ii}, p] = classifyAndUpdateState(app.nets{ii}, app.Zdata(app.count, ii) - app.Zdata_min(ii));
                        app.predictions(app.count, ii) = p;
                    end
                else
                    return;
                end
                
                % update display
                if ~mod(app.count,app.ui_refresh_rate)
%                     for ii=1:4
%                         cur_range = (app.count-app.ui_refresh_rate+1):app.count;
%             
%                         [app.nets{ii}, p] = classifyAndUpdateState(app.nets{ii}, app.Zdata(cur_range, ii)' - min(app.Zdata(1:app.count,ii)));
%                         app.predictions(cur_range, ii) = p;
%                     end
                    
                    % update plot
%                     addpoints(app.hZ_line(1), app.count, app.Zdata(app.count,1));
%                     app.hZ_marker(1).XData = app.count;
%                     app.hZ_marker(1).YData = app.Zdata(app.count,1);
%                     addpoints(app.hZ_line(2), app.count, app.Zdata(app.count,2));
%                     app.hZ_marker(2).XData = app.count;
%                     app.hZ_marker(2).YData = app.Zdata(app.count,2);
%                     addpoints(app.hZ_line(3), app.count, app.Zdata(app.count,3));
%                     app.hZ_marker(3).XData = app.count;
%                     app.hZ_marker(3).YData = app.Zdata(app.count,3);
%                     addpoints(app.hZ_line(4), app.count, app.Zdata(app.count,4));
%                     app.hZ_marker(4).XData = app.count;
%                     app.hZ_marker(4).YData = app.Zdata(app.count,4);
                    
                    % update labels
                    app.Z1.Value = app.Zdata(app.count,1);
                    app.Z2.Value = app.Zdata(app.count,2);
                    app.Z3.Value = app.Zdata(app.count,3);
                    app.Z4.Value = app.Zdata(app.count,4);
                    
                    % update lamps
                    app.enableLamps(false);
                    switch app.predictions(app.count,1)
                        case 'Modiolar'
                            app.ModiolarLamp1.Enable = true;
                        case 'Middle'
                            app.MiddleLamp1.Enable = true;
                        case 'Lateral'
                            app.LateralLamp1.Enable = true;
                    end
                    switch app.predictions(app.count,2)
                        case 'Modiolar'
                            app.ModiolarLamp2.Enable = true;
                        case 'Middle'
                            app.MiddleLamp2.Enable = true;
                        case 'Lateral'
                            app.LateralLamp2.Enable = true;
                    end
                    switch app.predictions(app.count,3)
                        case 'Modiolar'
                            app.ModiolarLamp3.Enable = true;
                        case 'Middle'
                            app.MiddleLamp3.Enable = true;
                        case 'Lateral'
                            app.LateralLamp3.Enable = true;
                    end
                    switch app.predictions(app.count,4)
                        case 'Modiolar'
                            app.ModiolarLamp4.Enable = true;
                        case 'Middle'
                            app.MiddleLamp4.Enable = true;
                        case 'Lateral'
                            app.LateralLamp4.Enable = true;
                    end                    
                end
            end
        end
        
        function enableLamps(app, true_false)
            app.ModiolarLamp1.Enable = true_false;
            app.MiddleLamp1.Enable   = true_false;
            app.LateralLamp1.Enable  = true_false;
            app.ModiolarLamp2.Enable = true_false;
            app.MiddleLamp2.Enable   = true_false;
            app.LateralLamp2.Enable  = true_false;
            app.ModiolarLamp3.Enable = true_false;
            app.MiddleLamp3.Enable   = true_false;
            app.LateralLamp3.Enable  = true_false;
            app.ModiolarLamp4.Enable = true_false;
            app.MiddleLamp4.Enable   = true_false;
            app.LateralLamp4.Enable  = true_false;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load trained network
            net = load('D:\Trevor\My Documents\MED lab\Cochlear R01\Impedance Sensing\Experiments\Dissertation data\Zsense_LSTMclassify-min.mat', 'netZsensing');
            net = net.netZsensing;
            net.resetState;
            app.nets = {net, net, net, net}; % create a copy for each channel
            
            % create update timer
            app.update_timer = timer;
            app.update_timer.TimerFcn = @app.UpdateCallback;
            app.update_timer.Period = app.update_timer_period; % [s]
            app.update_timer.ExecutionMode = 'fixedRate';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            
            app.s = serial(app.PortDropDown.Value, 'BaudRate',115200, 'Terminator','LF');
            fopen(app.s);
            
            pause(0.1)
            if ~isempty(instrfind)
                % enable components
                app.StartButton.Enable = true;
                app.DisconnectButton.Enable = true;
                app.EA_Image.Enable = true;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % query serial ports
            ports = seriallist;
            for ii = 1:length(ports)
                app.PortDropDown.Items{ii} = ports{ii};
            end
            
            % enable/disable connect button
            app.ConnectButton.Enable = logical(length(ports));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % enable components
            app.Z1.Enable = true;
            app.Z2.Enable = true;
            app.Z3.Enable = true;
            app.Z4.Enable = true;
            
            % setup plot
            app.hZ_line   = gobjects(4,1);
            app.hZ_marker = gobjects(4,1);
            for ii=1:4
                app.hZ_line(ii) = animatedline(app.Axes, 1, 1, 'Color', app.colors(ii,:));
                app.hZ_marker(ii) = line(app.Axes, 1, 1, 'Color', app.colors(ii,:), 'LineStyle','none', 'Marker','o', 'MarkerSize',8);
            end
            
            % send command to start running
            fprintf(app.s, 's');
            
            % close/open to flush buffer
            fclose(app.s);
            fopen(app.s);
            for ii=1:15
                fgetl(app.s);
            end

            data_rate = 1000/16.923497; % [Hz]
            app.max_samples = ceil(5*60*data_rate);
            n_channels = 4;
%             app.ui_refresh_rate = 10; % [samples]
            app.Zdata = nan(app.max_samples,n_channels);
            app.predictions = categorical(nan(app.max_samples,n_channels));
            app.count = 0;
            
            % begin reading data
            start(app.update_timer)
        end

        % Close request function: Impedance_Sensing
        function Impedance_SensingCloseRequest(app, event)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            delete(app)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            stop(app.update_timer)
            fclose(app.s);
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            
            while ~isempty(app.PortDropDown.Value)
                app.PortDropDown.Items{end} = '';
            end
            
            % disable components
            app.StartButton.Enable = false;
            app.DisconnectButton.Enable = false;
            app.EA_Image.Enable = false;
            app.enableLamps(false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Impedance_Sensing = uifigure('Visible', 'off');
            app.Impedance_Sensing.Name = 'UI Figure';
            app.Impedance_Sensing.CloseRequestFcn = createCallbackFcn(app, @Impedance_SensingCloseRequest, true);
            app.ModiolarLamp1 = uilamp(app.Impedance_Sensing);
            app.ModiolarLamp1.Enable = 'off';
            app.MiddleLamp1 = uilamp(app.Impedance_Sensing);
            app.MiddleLamp1.Enable = 'off';
            app.LateralLamp1 = uilamp(app.Impedance_Sensing);
            app.LateralLamp1.Enable = 'off';
            app.SerialPanel = uipanel(app.Impedance_Sensing);
            app.SerialPanel.Title = 'Serial';
            app.DisconnectButton = uibutton(app.SerialPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.SerialPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.RefreshButton = uibutton(app.SerialPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'refresh_icon.png';
            app.RefreshButton.Text = '';
            app.PortLabel = uilabel(app.SerialPanel);
            app.PortLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.SerialPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.StartButton = uibutton(app.Impedance_Sensing, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.EA_Image = uiimage(app.Impedance_Sensing);
            app.EA_Image.Enable = 'off';
            app.EA_Image.ImageSource = 'Flex28_electrode_array_MEDEL.svg';
            app.ModiolarLamp2 = uilamp(app.Impedance_Sensing);
            app.ModiolarLamp2.Enable = 'off';
            app.MiddleLamp2 = uilamp(app.Impedance_Sensing);
            app.MiddleLamp2.Enable = 'off';
            app.LateralLamp2 = uilamp(app.Impedance_Sensing);
            app.LateralLamp2.Enable = 'off';
            app.ModiolarLamp3 = uilamp(app.Impedance_Sensing);
            app.ModiolarLamp3.Enable = 'off';
            app.MiddleLamp3 = uilamp(app.Impedance_Sensing);
            app.MiddleLamp3.Enable = 'off';
            app.LateralLamp3 = uilamp(app.Impedance_Sensing);
            app.LateralLamp3.Enable = 'off';
            app.ModiolarLamp4 = uilamp(app.Impedance_Sensing);
            app.ModiolarLamp4.Enable = 'off';
            app.MiddleLamp4 = uilamp(app.Impedance_Sensing);
            app.MiddleLamp4.Enable = 'off';
            app.LateralLamp4 = uilamp(app.Impedance_Sensing);
            app.LateralLamp4.Enable = 'off';
            app.Z1 = uieditfield(app.Impedance_Sensing, 'numeric');
            app.Z1.Editable = 'off';
            app.Z1.Enable = 'off';
            app.Z2 = uieditfield(app.Impedance_Sensing, 'numeric');
            app.Z2.Editable = 'off';
            app.Z2.Enable = 'off';
            app.Z3 = uieditfield(app.Impedance_Sensing, 'numeric');
            app.Z3.Editable = 'off';
            app.Z3.Enable = 'off';
            app.Z4 = uieditfield(app.Impedance_Sensing, 'numeric');
            app.Z4.Editable = 'off';
            app.Z4.Enable = 'off';
            app.ModiolarLabel = uilabel(app.Impedance_Sensing);
            app.ModiolarLabel.Text = 'Modiolar';
            app.MiddleLabel = uilabel(app.Impedance_Sensing);
            app.MiddleLabel.Text = 'Middle';
            app.LateralLabel = uilabel(app.Impedance_Sensing);
            app.LateralLabel.Text = 'Lateral';
            app.Axes = uiaxes(app.Impedance_Sensing);
            title(app.Axes, '')
            xlabel(app.Axes, '')
            ylabel(app.Axes, '\Omega')
            app.Axes.YLim = [1000 5000];
            app.Axes.XTick = [];
            app.Axes.YTick = [1000 1500 2000 2500 3000 3500 4000 4500 5000];
            app.Axes.YTickLabel = {'1000'; '1500'; '2000'; '2500'; '3000'; '3500'; '4000'; '4500'; '5000'};
            % Show the figure after all components are created
            app.Impedance_Sensing.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zsensing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Impedance_Sensing)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Impedance_Sensing)
        end
    end
end